classdef pick_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SelectpicksTab                matlab.ui.container.Tab
        RobotPickSelectorLabel        matlab.ui.control.Label
        SendcommandtorobotButton      matlab.ui.control.Button
        MultiselectcolorsingleselectshapeLabel  matlab.ui.control.Label
        ColourListBoxLabel            matlab.ui.control.Label
        ColourListBox                 matlab.ui.control.ListBox
        ShapeLabel                    matlab.ui.control.Label
        ShapeListBox                  matlab.ui.control.ListBox
        PickstoperformLabel           matlab.ui.control.Label
        PickstoperformSpinner         matlab.ui.control.Spinner
        RobotreadyforcommandLabel     matlab.ui.control.Label
        RobotreadyforcommandLamp      matlab.ui.control.Lamp
        RobotperformingcommandLabel   matlab.ui.control.Label
        RobotperformingcommandLamp    matlab.ui.control.Lamp
        SpawnobjectsTab               matlab.ui.container.Tab
        ObjectSpawnerLabel            matlab.ui.control.Label
        SpawnobjectButton             matlab.ui.control.Button
        MultiselectcolorsingleselectshapeLabel_2  matlab.ui.control.Label
        ColorListBox_2Label           matlab.ui.control.Label
        ColorListBox_2                matlab.ui.control.ListBox
        ShapeListBox_2Label           matlab.ui.control.Label
        ShapeListBox_2                matlab.ui.control.ListBox
        EnvironmentreadyforspawnLampLabel  matlab.ui.control.Label
        EnvironmentreadyforspawnLamp  matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SendcommandtorobotButton
        function SendcommandtorobotButtonPushed(app, event)
            % SEND COMMAND TO THE ROBOT - ROS
            %
            %
            %
            %
            home;
            disp('Command to be sent:');
            disp(['Color/s: ' get(app.ColourListBox,'value')])
            disp(['Shape/s: ' get(app.ShapeListBox,'value')])
            disp(['Number of picks: ' num2str(get(app.PickstoperformSpinner,'value'))])
            disp('Command send to robot');
            
        end

        % Value changed function: PickstoperformSpinner
        function PickstoperformSpinnerValueChanged(app, event)
            value = app.PickstoperformSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectpicksTab = uitab(app.TabGroup);
            app.SelectpicksTab.Title = 'Select picks';
            app.RobotPickSelectorLabel = uilabel(app.SelectpicksTab);
            app.RobotPickSelectorLabel.Text = 'Robot Pick Selector';
            app.SendcommandtorobotButton = uibutton(app.SelectpicksTab, 'push');
            app.SendcommandtorobotButton.ButtonPushedFcn = createCallbackFcn(app, @SendcommandtorobotButtonPushed, true);
            app.SendcommandtorobotButton.Text = 'Send command to robot';
            app.MultiselectcolorsingleselectshapeLabel = uilabel(app.SelectpicksTab);
            app.MultiselectcolorsingleselectshapeLabel.Text = {'Multi select color + single select shape OR'; 'Single select color + multi select shape'};
            app.ColourListBoxLabel = uilabel(app.SelectpicksTab);
            app.ColourListBoxLabel.Text = 'Colour:';
            app.ColourListBox = uilistbox(app.SelectpicksTab);
            app.ColourListBox.Items = {'Red', 'Green', 'Blue'};
            app.ColourListBox.Multiselect = 'on';
            app.ColourListBox.Value = {'Red'};
            app.ShapeLabel = uilabel(app.SelectpicksTab);
            app.ShapeLabel.Text = 'Shape:';
            app.ShapeListBox = uilistbox(app.SelectpicksTab);
            app.ShapeListBox.Items = {'Circle', 'Square', 'Triangle', 'Pentagon'};
            app.ShapeListBox.Multiselect = 'on';
            app.ShapeListBox.Value = {'Circle'};
            app.PickstoperformLabel = uilabel(app.SelectpicksTab);
            app.PickstoperformLabel.Text = 'Picks to perform:';
            app.PickstoperformSpinner = uispinner(app.SelectpicksTab);
            app.PickstoperformSpinner.Limits = [1 20];
            app.PickstoperformSpinner.ValueChangedFcn = createCallbackFcn(app, @PickstoperformSpinnerValueChanged, true);
            app.PickstoperformSpinner.Value = 1;
            app.RobotreadyforcommandLabel = uilabel(app.SelectpicksTab);
            app.RobotreadyforcommandLabel.Text = {'Robot ready'; 'for command'};
            app.RobotreadyforcommandLamp = uilamp(app.SelectpicksTab);
            app.RobotperformingcommandLabel = uilabel(app.SelectpicksTab);
            app.RobotperformingcommandLabel.Text = {'Robot performing'; 'command'};
            app.RobotperformingcommandLamp = uilamp(app.SelectpicksTab);
            app.SpawnobjectsTab = uitab(app.TabGroup);
            app.SpawnobjectsTab.Title = 'Spawn objects';
            app.ObjectSpawnerLabel = uilabel(app.SpawnobjectsTab);
            app.ObjectSpawnerLabel.Text = 'Object Spawner';
            app.SpawnobjectButton = uibutton(app.SpawnobjectsTab, 'push');
            app.SpawnobjectButton.Text = 'Spawn object';
            app.MultiselectcolorsingleselectshapeLabel_2 = uilabel(app.SpawnobjectsTab);
            app.MultiselectcolorsingleselectshapeLabel_2.Text = {'Object will be inserted in random'; 'location and orientation'};
            app.ColorListBox_2Label = uilabel(app.SpawnobjectsTab);
            app.ColorListBox_2Label.Text = 'Color:';
            app.ColorListBox_2 = uilistbox(app.SpawnobjectsTab);
            app.ColorListBox_2.Items = {'Red', 'Green', 'Blue'};
            app.ColorListBox_2.Multiselect = 'on';
            app.ColorListBox_2.Value = {'Red'};
            app.ShapeListBox_2Label = uilabel(app.SpawnobjectsTab);
            app.ShapeListBox_2Label.Text = 'Shape:';
            app.ShapeListBox_2 = uilistbox(app.SpawnobjectsTab);
            app.ShapeListBox_2.Items = {'Circle', 'Square', 'Triangle', 'Pentagon'};
            app.ShapeListBox_2.Multiselect = 'on';
            app.ShapeListBox_2.Value = {'Circle'};
            app.EnvironmentreadyforspawnLampLabel = uilabel(app.SpawnobjectsTab);
            app.EnvironmentreadyforspawnLampLabel.Text = {'Environment ready'; 'for spawn'};
            app.EnvironmentreadyforspawnLamp = uilamp(app.SpawnobjectsTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pick_selector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end